classdef Numerical_Differentiation_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CloseButton               matlab.ui.control.Button
        text8_2                   matlab.ui.control.Label
        text8                     matlab.ui.control.Label
        OutputPanel               matlab.ui.container.Panel
        Approxfx0EditField        matlab.ui.control.NumericEditField
        Approxfx0Label            matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        InputsPanel               matlab.ui.container.Panel
        MethodDropDown            matlab.ui.control.DropDown
        MethodDropDownLabel       matlab.ui.control.Label
        CalculateButton           matlab.ui.control.Button
        FunctionEditField         matlab.ui.control.EditField
        FunctionfxEditFieldLabel  matlab.ui.control.Label
        StepSizehEditField        matlab.ui.control.NumericEditField
        StepSizehEditFieldLabel   matlab.ui.control.Label
        Pointx0EditField          matlab.ui.control.NumericEditField
        Pointx0EditFieldLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function method(app, event)
            method = app.MethodDropDown.Value;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Read inputs
            fStr = app.FunctionEditField.Value;
            x0  = app.Pointx0EditField.Value;
            method = app.MethodDropDown.Value;
            h   = app.StepSizehEditField.Value;
            % Convert string to function handle
            try
                f = str2func(['@(x) ' fStr]);
            catch
                uialert(app.UIFigure, 'Invalid function expression.', 'Input Error');
                return;
            end
            % Compute derivative based on selected method
            derVal =  numerical_differentiation(f, x0, h, method); 
            % Display result
            app.Approxfx0EditField.Value = derVal;
            % Plot function and tangent line
            xs = linspace(x0-3*h, x0+3*h, 200);
            ys = arrayfun(f, xs);
            plot(app.UIAxes, xs, ys, 'LineWidth', 2);
            hold(app.UIAxes, 'on');
            tangent = derVal*(xs - x0) + f(x0);
            plot(app.UIAxes, xs, tangent, '--', 'LineWidth', 1.5);
            plot(app.UIAxes, x0,f(x0),'ro');
            hold(app.UIAxes, 'off');
            axis(app.UIAxes, 'tight');
            xlabel(app.UIAxes, 'x'); ylabel(app.UIAxes, 'f(x)');
            legend(app.UIAxes, {'f(x)', 'Tangent at x0'},'Location','best');
            title(app.UIAxes,[method, ' Method'])
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.Pointx0EditFieldLabel = uilabel(app.InputsPanel);
            app.Pointx0EditFieldLabel.Text = 'Point x0:';
            app.Pointx0EditField = uieditfield(app.InputsPanel, 'numeric');
            app.Pointx0EditField.Value = 0.1;
            app.StepSizehEditFieldLabel = uilabel(app.InputsPanel);
            app.StepSizehEditFieldLabel.Text = 'Step Size h:';
            app.StepSizehEditField = uieditfield(app.InputsPanel, 'numeric');
            app.StepSizehEditField.Value = 0.2;
            app.FunctionfxEditFieldLabel = uilabel(app.InputsPanel);
            app.FunctionfxEditFieldLabel.Text = 'Function f(x):';
            app.FunctionEditField = uieditfield(app.InputsPanel, 'text');
            app.FunctionEditField.Value = 'sin(2*x)';
            app.CalculateButton = uibutton(app.InputsPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MethodDropDownLabel = uilabel(app.InputsPanel);
            app.MethodDropDownLabel.Text = 'Method:';
            app.MethodDropDown = uidropdown(app.InputsPanel);
            app.MethodDropDown.Items = {'Forward Difference', 'Backward Difference', 'Three-Point (central)', 'Three-Point (forward)', 'Three-Point (backward)', 'Five-Point (central)'};
            app.MethodDropDown.Value = 'Forward Difference';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.UIAxes = uiaxes(app.OutputPanel);
            title(app.UIAxes, 'Numeircal Differentiation Method')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Approxfx0Label = uilabel(app.OutputPanel);
            app.Approxfx0Label.Text = '''Approx. f''(x0):''';
            app.Approxfx0EditField = uieditfield(app.OutputPanel, 'numeric');
            app.Approxfx0EditField.ValueDisplayFormat = '%8.4e';
            app.text8 = uilabel(app.UIFigure);
            app.text8.Text = {'ACCOMPLISH - UMass Dartmouth Developed by Zheng "Leslie" Chen '; 'email: zchen2@umassd.edu'};
            app.text8_2 = uilabel(app.UIFigure);
            app.text8_2.Text = {'ACCOMPLISH - UMass Dartmouth Developed by Zheng "Leslie" Chen '; 'email: zchen2@umassd.edu'};
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Numerical_Differentiation_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end