classdef odesolver_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        edit_order     matlab.ui.control.EditField
        text_order     matlab.ui.control.Label
        edit_no_steps  matlab.ui.control.EditField
        text_no_steps  matlab.ui.control.Label
        text12_2       matlab.ui.control.Label
        popupmenu1     matlab.ui.control.DropDown
        Button         matlab.ui.control.Button
        text8          matlab.ui.control.Label
        pushbutton2    matlab.ui.control.Button
        text12         matlab.ui.control.Label
        text11         matlab.ui.control.Label
        popupmenu2     matlab.ui.control.DropDown
        text10         matlab.ui.control.Label
        checkbox5      matlab.ui.control.CheckBox
        pushbutton1    matlab.ui.control.Button
        text4          matlab.ui.control.Label
        edit4          matlab.ui.control.EditField
        text3          matlab.ui.control.Label
        text2          matlab.ui.control.Label
        edit3          matlab.ui.control.EditField
        edit2          matlab.ui.control.EditField
        edit1          matlab.ui.control.EditField
        axes1          matlab.ui.control.UIAxes
    end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Written by Zheng "Leslie" CHEN, University of Massachusetts Dartmouth
% Email: zchen2@umassd.edu
% This app is created for UMassD ACCOMPLISH program.
% The codes are converted and modified from an previous version generated 
% by GUIDE.
% Date: Aug. 5, 2022
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    methods (Access = private)
        function axes3_CreateFcn(app, hObject, eventdata, handles)
            
            % hObject    handle to axes3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            imshow('upt.gif');
        end
        
        function popupmenu1_CreateFcn(app, hObject, eventdata, handles)
            
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
%         function dy = yprime(app, t, y)
%             global dyexpression
%             dy=eval(dyexpression);
%         end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function odesolver_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Callback function
        function checkbox1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if get(handles.checkbox1,'Value')==1
                set(handles.checkbox2,'Value',0);
                set(handles.checkbox3,'Value',0);
                set(handles.checkbox4,'Value',0);
                set(handles.checkbox6,'Value',0);
                set(handles.checkbox7,'Value',0);
                set(handles.checkbox8,'Value',0);
            end
        end

        % Callback function
        function checkbox2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if get(handles.checkbox2,'Value')==1
                set(handles.checkbox1,'Value',0);
                set(handles.checkbox3,'Value',0);
                set(handles.checkbox4,'Value',0);
                set(handles.checkbox6,'Value',0);
                set(handles.checkbox7,'Value',0);
                set(handles.checkbox8,'Value',0);
            end
        end

        % Callback function
        function checkbox3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if get(handles.checkbox3,'Value')==1
                set(handles.checkbox2,'Value',0);
                set(handles.checkbox1,'Value',0);
                set(handles.checkbox4,'Value',0);
                set(handles.checkbox5,'Value',0);
                set(handles.checkbox6,'Value',0);
                set(handles.checkbox7,'Value',0);
                set(handles.checkbox8,'Value',0);
            end
        end

        % Callback function
        function checkbox4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


            if get(handles.checkbox4,'Value')==1
                set(handles.checkbox2,'Value',0);
                set(handles.checkbox3,'Value',0);
                set(handles.checkbox1,'Value',0);
                set(handles.checkbox6,'Value',0);
                set(handles.checkbox7,'Value',0);
                set(handles.checkbox8,'Value',0);
            end
        end

        % Callback function
        function checkbox6_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if get(handles.checkbox6,'Value')==1
                set(handles.checkbox2,'Value',0);
                set(handles.checkbox3,'Value',0);
                set(handles.checkbox4,'Value',0);
                set(handles.checkbox1,'Value',0);
                set(handles.checkbox7,'Value',0);
                set(handles.checkbox8,'Value',0);
            end
        end

        % Callback function
        function checkbox7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox7
            if get(handles.checkbox7,'Value')==1
                set(handles.checkbox2,'Value',0);
                set(handles.checkbox3,'Value',0);
                set(handles.checkbox4,'Value',0);
                set(handles.checkbox6,'Value',0);
                set(handles.checkbox1,'Value',0);
                set(handles.checkbox8,'Value',0);
            end
        end

        % Callback function
        function checkbox8_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if get(handles.checkbox8,'Value')==1
                set(handles.checkbox2,'Value',0);
                set(handles.checkbox3,'Value',0);
                set(handles.checkbox4,'Value',0);
                set(handles.checkbox6,'Value',0);
                set(handles.checkbox7,'Value',0);
                set(handles.checkbox1,'Value',0);
            end
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
%             global dyexpression
            dyexpression=get(handles.edit1,'String');
            fy = str2func(['@(t,y)' dyexpression]);
            t0=str2double(get(handles.edit2,'String'));
            tf=str2double(get(handles.edit3,'String'));
            ICvalue=str2double(get(handles.edit4,'String'));
            method = get(handles.popupmenu2,'Value');

            if method < 8
                N = str2double(get(handles.edit_no_steps,'String'));
            end
            
            if ismember(method,[3])
                order = str2double(get(handles.edit_order,'String'))
            end

            switch method
                case 2 % Forward Euler method
                    [t,y] = FE(fy,[t0 tf],ICvalue,N);
                case 3 % Taylor Method (choose order)
                    [t,y] = Taylor(fy,[t0 tf],ICvalue,N,order);
                case 4 % second-order Runge-Kutta Method - Midpoint method
                    [t,y] = RK2Midpoint(fy,[t0 tf],ICvalue,N);
                case 5 % second-order Runge-Kutta Method - Modified Euler method
                    [t,y] = RK2ModEuler(fy,[t0 tf],ICvalue,N);
                case 6 % second-order Runge-Kutta Method - Heun's method
                    [t,y] = RK2Heun(fy,[t0 tf],ICvalue,N);
                case 7 % Runge-Kutta Method of order 4
                    [t,y] = RK4(fy,[t0 tf],ICvalue,N);
                case 8 % Matlab build-in ode45
                    [t,y] = ode45(fy,[t0 tf],ICvalue);
                case 9 % Matlab build-in ode15s
                    [t,y] = ode15s(fy,[t0 tf],ICvalue);
                case 10 % Matlab build-in ode23
                    [t,y] = ode23(fy,[t0 tf],ICvalue);
                case 11 % Matlab build-in ode23s
                    [t,y] = ode23s(fy,[t0 tf],ICvalue);
                case 12 % Matlab build-in ode23t
                    [t,y] = ode23t(fy,[t0 tf],ICvalue);
                case 13 % Matlab build-in ode113
                    [t,y] = ode113(fy,[t0 tf],ICvalue);
                otherwise
                    disp('Wrong selection of ODE solver!')
            end

            switch get(handles.popupmenu1,'Value')
                case 1
                    linecolor='b';
                case 2
                    linecolor='g';
                case 3
                    linecolor='r';
                case 4
                    linecolor='m';
                case 5
                    linecolor='k';
                otherwise
            end
            if get(handles.checkbox5,'Value')==0
                hold on
            elseif get(handles.checkbox5,'Value')==1
                hold off
            end
            axes(handles.axes1)
            plot(t,y(:,1),linecolor,'LineWidth',1)
            xlabel('t')
            ylabel('y')
            
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            close
        end

        % Callback function: edit_no_steps, edit_order, popupmenu2
        function popupmenu2_Callback(app, event)
%             changingValue = event.Value;
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            method = get(handles.popupmenu2,'Value');

            if method > 7         
                set(handles.text_no_steps,'Visible','off');
                set(handles.edit_no_steps,'Visible','off');
            else
                set(handles.text_no_steps,'Visible','on');
                set(handles.edit_no_steps,'Visible','on');
            end    
            if ismember(method,[3])
                set(handles.edit_order,'Visible','on');
                set(handles.text_order,'Visible','on');
            else
                set(handles.edit_order,'Visible','off');
                set(handles.text_order,'Visible','off');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'odesolver';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.edit1 = uieditfield(app.figure1, 'text');
            app.edit1.Tag = 'edit1';
            app.edit1.Value = 'y-t^2+1';
            app.edit2 = uieditfield(app.figure1, 'text');
            app.edit2.Tag = 'edit2';
            app.edit2.Value = '0';
            app.edit3 = uieditfield(app.figure1, 'text');
            app.edit3.Tag = 'edit3';
            app.edit3.Value = '2';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'to';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Initial value';
            app.edit4 = uieditfield(app.figure1, 'text');
            app.edit4.Tag = 'edit4';
            app.edit4.Value = '0.5';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'To solve ODE: y''=f(y,t)';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Solve';
            app.checkbox5 = uicheckbox(app.figure1);
            app.checkbox5.Tag = 'checkbox5';
            app.checkbox5.Text = 'Do not plot over old answers';
            app.text10 = uilabel(app.figure1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'from';
            app.popupmenu2 = uidropdown(app.figure1);
            app.popupmenu2.Items = {'Choose a solver:', 'Forward Euler', 'Taylor Method (choose order)', 'second-order Runge-Kutta Method - Midpoint method', 'second-order Runge-Kutta Method - Modified Euler method', 'second-order Runge-Kutta Method - Heun''s method', 'Runge-Kutta Method of order 4', 'Matlab ode45', 'Matlab ode15s', 'Matlab ode23', 'Matlab ode23s', 'Matlab ode23t', 'Matlab ode113'};
            app.popupmenu2.ValueChangedFcn = createCallbackFcn(app, @popupmenu2_Callback, true);
            app.popupmenu2.Tag = 'popupmenu2';
            app.popupmenu2.Value = 'Choose a solver:';
            app.text11 = uilabel(app.figure1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'f(y,t) =';
            app.text12 = uilabel(app.figure1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'ODE solver';
            app.pushbutton2 = uibutton(app.figure1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = 'Close';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = {'ACCOMPLISH - UMass Dartmouth Developed by Zheng "Leslie" Chen '; 'email: zchen2@umassd.edu'};
            app.Button = uibutton(app.figure1, 'push');
            app.popupmenu1 = uidropdown(app.figure1);
            app.popupmenu1.Items = {'Blue', 'Green', 'Red', 'Magenta', 'Black'};
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'Blue';
            app.text12_2 = uilabel(app.figure1);
            app.text12_2.Tag = 'text12';
            app.text12_2.Text = 'line color';
            app.text_no_steps = uilabel(app.figure1);
            app.text_no_steps.Tag = 'text_no_steps';
            app.text_no_steps.Visible = 'off';
            app.text_no_steps.Text = 'No. of time steps';
            app.edit_no_steps = uieditfield(app.figure1, 'text');
            app.edit_no_steps.ValueChangingFcn = createCallbackFcn(app, @popupmenu2_Callback, true);
            app.edit_no_steps.Tag = 'edit_no_steps';
            app.edit_no_steps.Visible = 'off';
            app.edit_no_steps.Value = '100';
            app.text_order = uilabel(app.figure1);
            app.text_order.Tag = 'text_order';
            app.text_order.Visible = 'off';
            app.text_order.Text = 'Order';
            app.edit_order = uieditfield(app.figure1, 'text');
            app.edit_order.ValueChangingFcn = createCallbackFcn(app, @popupmenu2_Callback, true);
            app.edit_order.Tag = 'edit_order';
            app.edit_order.Visible = 'off';
            app.edit_order.Value = '2';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = odesolver_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)odesolver_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end